classdef SymbolicDiffIntApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        xMaxEditField                  matlab.ui.control.NumericEditField
        xMaxEditFieldLabel             matlab.ui.control.Label
        xMinEditField                  matlab.ui.control.NumericEditField
        xMinEditFieldLabel             matlab.ui.control.Label
        resultLabel                    matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        withrespecttoDropDown          matlab.ui.control.DropDown
        withrespecttoDropDownLabel     matlab.ui.control.Label
        OperationButtonGroup           matlab.ui.container.ButtonGroup
        IntegrateButton                matlab.ui.control.RadioButton
        DifferentiateButton            matlab.ui.control.RadioButton
        InputExpressionEditField       matlab.ui.control.EditField
        InputExpressionEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updatePlot(app)
            % try
                % get user input expression and variable
                expression = (str2sym(app.InputExpressionEditField.Value));
                intVar = app.withrespecttoDropDown.Value;
                xmin = app.xMinEditField.Value;
                xmax = app.xMaxEditField.Value;

                vars = symvar(expression);
                if ismember(sym('y'), vars) && intVar ~= sym('y')
                    expression = subs(expression, sym('y'), 0);
                elseif ismember(sym('x'), vars) && intVar ~= sym('x')
                    expression = subs(expression, sym('x'), 0);
                end

                % modify the expression based on selected operation
                if (app.IntegrateButton.Value)
                    modifiedExpression = int(expression, intVar);
                    chosenOperation = "Integral";
                elseif (app.DifferentiateButton.Value)
                    modifiedExpression = diff(expression, intVar);
                    chosenOperation = "Differential";
                end
                
                % solve for y
                % modifiedExpression = isolate(modifiedExpression, sym('y'));
                
                % change result label
                app.resultLabel.Text = string(modifiedExpression);

                % plot both original and modified expressions
                fplot(app.UIAxes, [expression, modifiedExpression], [xmin, xmax]);
                legend(app.UIAxes, "Original", chosenOperation);

            % catch ME
            %     msgbox(ME.message);
            % end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InputExpressionEditFieldLabel = uilabel(app.UIFigure);
            app.InputExpressionEditFieldLabel.Text = 'Input Expression';
            app.InputExpressionEditField = uieditfield(app.UIFigure, 'text');
            app.InputExpressionEditField.Placeholder = 'x^2 + 2*x - 3';
            app.OperationButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationButtonGroup.Title = 'Operation';
            app.DifferentiateButton = uiradiobutton(app.OperationButtonGroup);
            app.DifferentiateButton.Text = 'Differentiate';
            app.DifferentiateButton.Value = true;
            app.IntegrateButton = uiradiobutton(app.OperationButtonGroup);
            app.IntegrateButton.Text = 'Integrate';
            app.withrespecttoDropDownLabel = uilabel(app.UIFigure);
            app.withrespecttoDropDownLabel.Text = 'with respect to';
            app.withrespecttoDropDown = uidropdown(app.UIFigure);
            app.withrespecttoDropDown.Items = {'x', 'y'};
            app.withrespecttoDropDown.Value = 'x';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Text = 'result';
            app.xMinEditFieldLabel = uilabel(app.UIFigure);
            app.xMinEditFieldLabel.Text = 'x Min';
            app.xMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMinEditField.Value = -10;
            app.xMaxEditFieldLabel = uilabel(app.UIFigure);
            app.xMaxEditFieldLabel.Text = 'x Max';
            app.xMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMaxEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolicDiffIntApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end