classdef DCMotorPID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        tfRandomSeed              matlab.ui.control.NumericEditField
        RandomSeedEditFieldLabel  matlab.ui.control.Label
        tfDerivative              matlab.ui.control.NumericEditField
        DerivativeCoefficientEditFieldLabel  matlab.ui.control.Label
        tfIntegral                matlab.ui.control.NumericEditField
        IntegralCoefficientEditFieldLabel  matlab.ui.control.Label
        tfProportional            matlab.ui.control.NumericEditField
        ProportionalCoefficientEditFieldLabel  matlab.ui.control.Label
        tfGoal                    matlab.ui.control.NumericEditField
        SetpointradsLabel         matlab.ui.control.Label
        btnGo                     matlab.ui.control.Button
        pltPosition               matlab.ui.control.UIAxes
        pltMotorSpeed             matlab.ui.control.UIAxes
        pltDeviation              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        previousData struct = struct('t', [], 'sensor', [], 'leftMotor', [], 'rightMotor', [], 'position', []) % Structure to store the previous dataset
        currentData struct = struct('t', [], 'sensor', [], 'leftMotor', [], 'rightMotor', [], 'position', []) % Structure to store the current dataset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGo
        function btnGoButtonPushed(app, event)
            kP = app.tfProportional.Value;
            kI = app.tfIntegral.Value;
            kD = app.tfDerivative.Value;
            goal = app.tfGoal.Value;
            seed = app.tfRandomSeed.Value;

            % Run the simulation to get current data
            [t, sensor, leftMotor, rightMotor, position] = simulateLineFollowerPololu(kP, kI, kD, goal, seed);

            % Shift current data to previous data
            app.previousData = app.currentData;

            % Store new data as current data
            app.currentData.t = t;
            app.currentData.sensor = sensor;
            app.currentData.leftMotor = leftMotor;
            app.currentData.rightMotor = rightMotor;
            app.currentData.position = position;

            % Clear previous plots to ensure only the last two datasets are displayed
            cla(app.pltDeviation);
            cla(app.pltMotorSpeed);
            cla(app.pltPosition);

            % Plotting on Deviation Plot
            hold(app.pltDeviation, 'on');
            if ~isempty(app.previousData.t)
                % Plot previous data in lighter color
                plot(app.pltDeviation, app.previousData.t, app.previousData.sensor, 'Color', [0.7, 0.7, 1], 'LineStyle', '--'); % Light blue
            end
            % Plot current data in regular color
            plot(app.pltDeviation, app.currentData.t, app.currentData.sensor, 'b');
            hold(app.pltDeviation, 'off');

            % Plotting on Motor Speed Plot
            hold(app.pltMotorSpeed, 'on');
            if ~isempty(app.previousData.t)
                % Plot previous data in lighter colors
                plot(app.pltMotorSpeed, app.previousData.t, app.previousData.leftMotor, 'Color', [0.7, 0.7, 1], 'LineStyle', '--'); % Light blue for left motor
                plot(app.pltMotorSpeed, app.previousData.t, app.previousData.rightMotor, 'Color', [1, 0.7, 0.7], 'LineStyle', '--'); % Light red for right motor
            end
            % Plot current data in regular colors
            plot(app.pltMotorSpeed, app.currentData.t, app.currentData.leftMotor, 'b', app.currentData.t, app.currentData.rightMotor, 'r');
            hold(app.pltMotorSpeed, 'off');

            % Plotting on Position Plot
            hold(app.pltPosition, 'on');
            if ~isempty(app.previousData.t)
                % Plot previous position data in lighter color
                plot(app.pltPosition, app.previousData.t, app.previousData.position, 'Color', [0.7, 0.7, 0.7], 'LineStyle', '--'); % Light gray
            end
            % Plot current position data in regular color
            plot(app.pltPosition, app.currentData.t, app.currentData.position, 'k');
            hold(app.pltPosition, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pltDeviation = uiaxes(app.UIFigure);
            title(app.pltDeviation, 'Deviation')
            xlabel(app.pltDeviation, 'Time (s)')
            ylabel(app.pltDeviation, 'Deviation')
            zlabel(app.pltDeviation, 'Z')
            app.pltMotorSpeed = uiaxes(app.UIFigure);
            title(app.pltMotorSpeed, 'Motor Speeds')
            xlabel(app.pltMotorSpeed, 'Time (s)')
            ylabel(app.pltMotorSpeed, 'Speed (0-255)')
            zlabel(app.pltMotorSpeed, 'Z')
            app.pltPosition = uiaxes(app.UIFigure);
            title(app.pltPosition, 'Position')
            xlabel(app.pltPosition, 'Time (s)')
            ylabel(app.pltPosition, 'Position')
            zlabel(app.pltPosition, 'Z')
            app.btnGo = uibutton(app.UIFigure, 'push');
            app.btnGo.ButtonPushedFcn = createCallbackFcn(app, @btnGoButtonPushed, true);
            app.btnGo.Text = 'GO';
            app.SetpointradsLabel = uilabel(app.UIFigure);
            app.SetpointradsLabel.Text = 'Setpoint (0-7000)';
            app.tfGoal = uieditfield(app.UIFigure, 'numeric');
            app.tfGoal.Value = 3500;
            app.ProportionalCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalCoefficientEditFieldLabel.Text = 'Proportional Coefficient';
            app.tfProportional = uieditfield(app.UIFigure, 'numeric');
            app.tfProportional.Value = 1;
            app.IntegralCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralCoefficientEditFieldLabel.Text = 'Integral Coefficient';
            app.tfIntegral = uieditfield(app.UIFigure, 'numeric');
            app.tfIntegral.Value = 0.1;
            app.DerivativeCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeCoefficientEditFieldLabel.Text = 'Derivative Coefficient';
            app.tfDerivative = uieditfield(app.UIFigure, 'numeric');
            app.tfDerivative.Value = 0.5;
            app.RandomSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RandomSeedEditFieldLabel.Text = 'Random Seed';
            app.tfRandomSeed = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCMotorPID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end