classdef PIDAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        tfGoal                          matlab.ui.control.NumericEditField
        SetpointmEditFieldLabel         matlab.ui.control.Label
        btnGo                           matlab.ui.control.Button
        tfDerivative                    matlab.ui.control.EditField
        DerivativeCoefficientEditFieldLabel  matlab.ui.control.Label
        tfIntegral                      matlab.ui.control.EditField
        IntegralCoefficientEditFieldLabel  matlab.ui.control.Label
        tfProportional                  matlab.ui.control.EditField
        ProportionalCoefficientEditFieldLabel  matlab.ui.control.Label
        tfSpringConstant                matlab.ui.control.EditField
        SpringConstantNmEditFieldLabel  matlab.ui.control.Label
        tfDampening                     matlab.ui.control.EditField
        DampeningCoefficientNsmEditFieldLabel  matlab.ui.control.Label
        tfMass                          matlab.ui.control.EditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        pltAnalysis                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGo
        function btnGoButtonPushed(app, event)
            m = str2double(app.tfMass.Value);
            c = str2double(app.tfDampening.Value);
            k = str2double(app.tfSpringConstant.Value);
            kP = str2double(app.tfProportional.Value);
            kI = str2double(app.tfIntegral.Value);
            kD = str2double(app.tfDerivative.Value);
            goal = app.tfGoal.Value;

            [t, x] = simulatePIDControl(m, c, k, kP, kI, kD, goal);
            plot(app.pltAnalysis, t, x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pltAnalysis = uiaxes(app.UIFigure);
            title(app.pltAnalysis, 'PID Control: Position Response')
            xlabel(app.pltAnalysis, 'Time (s)')
            ylabel(app.pltAnalysis, 'Position (m)')
            zlabel(app.pltAnalysis, 'Z')
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.tfMass = uieditfield(app.UIFigure, 'text');
            app.tfMass.InputType = 'digits';
            app.tfMass.Value = '1';
            app.DampeningCoefficientNsmEditFieldLabel = uilabel(app.UIFigure);
            app.DampeningCoefficientNsmEditFieldLabel.Text = 'Dampening Coefficient (N*s/m)';
            app.tfDampening = uieditfield(app.UIFigure, 'text');
            app.tfDampening.InputType = 'digits';
            app.tfDampening.Value = '5';
            app.SpringConstantNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringConstantNmEditFieldLabel.Text = 'Spring Constant N/m';
            app.tfSpringConstant = uieditfield(app.UIFigure, 'text');
            app.tfSpringConstant.InputType = 'digits';
            app.tfSpringConstant.Value = '20';
            app.ProportionalCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalCoefficientEditFieldLabel.Text = 'Proportional Coefficient';
            app.tfProportional = uieditfield(app.UIFigure, 'text');
            app.tfProportional.InputType = 'digits';
            app.tfProportional.Value = '100';
            app.IntegralCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralCoefficientEditFieldLabel.Text = 'Integral Coefficient';
            app.tfIntegral = uieditfield(app.UIFigure, 'text');
            app.tfIntegral.InputType = 'digits';
            app.tfIntegral.Value = '1';
            app.DerivativeCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeCoefficientEditFieldLabel.Text = 'Derivative Coefficient';
            app.tfDerivative = uieditfield(app.UIFigure, 'text');
            app.tfDerivative.InputType = 'digits';
            app.tfDerivative.Value = '10';
            app.btnGo = uibutton(app.UIFigure, 'push');
            app.btnGo.ButtonPushedFcn = createCallbackFcn(app, @btnGoButtonPushed, true);
            app.btnGo.Text = 'GO';
            app.SetpointmEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointmEditFieldLabel.Text = 'Setpoint (m)';
            app.tfGoal = uieditfield(app.UIFigure, 'numeric');
            app.tfGoal.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end